classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        String1Button      matlab.ui.control.Button
        String2Button      matlab.ui.control.Button
        String3Button      matlab.ui.control.Button
        GmajorchordButton  matlab.ui.control.Button
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: String1Button
        function String1ButtonPushed(app, event)
            fs=4400;
x = rand(1,100);
x = [ x zeros(1,5000)];
num = 1;
den = [ 1 zeros(1,99) -0.5 -0.5 ];
y = filter (num,den,x);
sound(y,fs);
plot(app.UIAxes,y)
        end

        % Button pushed function: String2Button
        function String2ButtonPushed(app, event)
            fs=6100;
x = rand(1,100);
x = [ x zeros(1,5000)];
num = 1;
den = [ 1 zeros(1,99) -0.5 -0.5 ];
y = filter (num,den,x);
sound(y,fs);
plot(app.UIAxes,y)
        end

        % Button pushed function: String3Button
        function String3ButtonPushed(app, event)
            fs=8000;
x = rand(1,100);
x = [ x zeros(1,5000)];
num = 1;
den = [ 1 zeros(1,99) -0.5 -0.5 ];
y = filter (num,den,x);
sound(y,fs);
plot(app.UIAxes,y)
        end

        % Button pushed function: GmajorchordButton
        function GmajorchordButtonPushed(app, event)
            clc;
close all;

Fs       = 64100;
A        = 110; % The A string of a guitar is normally tuned to 110 Hz
Eoffset  = -5;
Doffset  =  5;
Goffset  = 10;
Boffset  = 14;
E2offset = 19;


F = linspace(1/Fs, 1000, 2^12);

fret = [3 2 0 0 0 3];
delay = [round(Fs/(A*2^((fret(1)+Eoffset)/12))),
    round(Fs/(A*2^(fret(2)/12))), 
    round(Fs/(A*2^((fret(3)+Doffset)/12))), 
    round(Fs/(A*2^((fret(4)+Goffset)/12))), 
    round(Fs/(A*2^((fret(5)+Boffset)/12))), 
    round(Fs/(A*2^((fret(6)+E2offset)/12)))];

x = rand(1,100); %noise burst
x=[x zeros(1,4*Fs)];  
b = cell(length(delay),1);
a = cell(length(delay),1);
H = zeros(length(delay),4096);
note = zeros(length(x),length(delay));
for indx = 1:length(delay)
    
    b{indx} = 1;
    a{indx} = [1 zeros(1, delay(indx)-1) -0.5 -0.5];
    zi = rand(max(length(b{indx}),length(a{indx}))-1,1); %filter delay
    note(:,indx) = filter(b{indx},a{indx},x,zi);
    
   note(:, indx) = note(:, indx)-mean(note(:, indx));
 
    [H(indx,:),W] = freqz(b{indx}, a{indx},F, Fs);
    
end
combinedNote = sum(note,2);
combinedNote = combinedNote/max(abs(combinedNote));
sound(combinedNote, Fs); 
plot(app.UIAxes,note)
        end

        % Callback function
        function UIAxesButtonDown(app, event)
      
        end

        % Button down function: UIAxes
        function UIAxesButtonDown2(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.String1Button = uibutton(app.UIFigure, 'push');
            app.String1Button.ButtonPushedFcn = createCallbackFcn(app, @String1ButtonPushed, true);
            app.String1Button.Text = 'String 1';
            app.String2Button = uibutton(app.UIFigure, 'push');
            app.String2Button.ButtonPushedFcn = createCallbackFcn(app, @String2ButtonPushed, true);
            app.String2Button.Text = 'String 2';
            app.String3Button = uibutton(app.UIFigure, 'push');
            app.String3Button.ButtonPushedFcn = createCallbackFcn(app, @String3ButtonPushed, true);
            app.String3Button.Text = 'String 3';
            app.GmajorchordButton = uibutton(app.UIFigure, 'push');
            app.GmajorchordButton.ButtonPushedFcn = createCallbackFcn(app, @GmajorchordButtonPushed, true);
            app.GmajorchordButton.Text = {'G major chord'; ''};
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Sound')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown2, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end